classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        zoomEditField        matlab.ui.control.NumericEditField
        zoomEditFieldLabel   matlab.ui.control.Label
        chooseButton         matlab.ui.control.Button
        shuffleButton        matlab.ui.control.Button
        UIAxesIm3FT          matlab.ui.control.UIAxes
        UIAxesIm2FT          matlab.ui.control.UIAxes
        UIAxesIm3            matlab.ui.control.UIAxes
        UIAxesIm2            matlab.ui.control.UIAxes
        UIAxesIm1FT          matlab.ui.control.UIAxes
        UIAxesIm1            matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        cutEditField         matlab.ui.control.NumericEditField
        cutEditFieldLabel    matlab.ui.control.Label
        sigmaSlider_2        matlab.ui.control.Slider
        Label_2              matlab.ui.control.Label
        sigmaSlider          matlab.ui.control.Slider
        Label                matlab.ui.control.Label
        sigmaEditField       matlab.ui.control.NumericEditField
        sigmaEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Param; % Collection of parameters, initial values passed in
        Anamesi; %input directory, as given by cleanfolder() 
        image1;
        image2;
        image3; %indices of subimages to display?
        Posi; %list of positions of subimages
        regl; %indices of possible subimages?
        num1;
        num2;
        num3; %String indicating indices of subimage displayed? app 
        % text label component above?
        sub1;
        sub2;
        sub3; %data in subwindows 1,2,3
        wins; %list of currently displayed subfigure indices
        fftsub1;
        fftsub2;
        fftsub3; %Fourier transformed subimages
        spsub1; 
        spsub2;
        spsub3; %gaussian filtered Fourier transformed subimages
        fftsubcut1;
        fftsubcut2;
        fftsubcut3; %cut and filtered FT
    end

    methods (Access = private)
        %migrate GUIDE functions "handles = f(handles)"
        %to functions "f(app)" which changes app's properties
        
        function calculregion(app) 
            [app.Posi,app.regl] = Position_gui(app.Param);
        end

        function affichim(app) 
            %choose image from folder (timepoints)
            a = choose_image(app.Param.name{1},app.Anamesi,app.Param.tsart);

            if app.image1 ~=0 %subimages already chosen
                win1 = app.image1;
                win2 = app.image2;
                win3 = app.image3;
            elseif app.regl == 1 %only one subimage available
                win1 = 1;
                win2 = 1;
                win3 = 1;
            else %TODO could be random int
                win1 = round(rand(1)*app.regl);
                win2 = round(rand(1)*app.regl);
                win3 = round(rand(1)*app.regl);

            end

            set(app.num1,'String',['Number: ' num2str(win1)]);
            set(app.num2,'String',['Number: ' num2str(win2)]);
            set(app.num3,'String',['Number: ' num2str(win3)]);


            %cut subimage's data out of image and display
            x = app.Posi(win1,1);
            y = app.Posi(win1,2);
            app.sub1=a(y:y+app.Param.subwindow_size-1,x:x+app.Param.subwindow_size-1);
            imagesc(app.sub1,Parent=app.UIAxesIm1)
            set(app.UIAxesIm1,'Visible','off') %I don't know why, also seems
            %to have no effect

            x = app.Posi(win2,1);
            y = app.Posi(win2,2);
            app.sub2=a(y:y+app.Param.subwindow_size-1,x:x+app.Param.subwindow_size-1);
            imagesc(app.sub2,'Parent',app.UIAxesIm2)
            set(app.UIAxesIm2,'Visible','off')

            x = app.Posi(win3,1);
            y = app.Posi(win3,2);
            app.sub3=a(y:y+app.Param.subwindow_size-1,x:x+app.Param.subwindow_size-1);
            imagesc(app.sub3,'Parent',app.UIAxesIm3)
            set(app.UIAxesIm3,'Visible','off')

            app.wins = [win1,win2,win3];
        end

        function fftcalculus(app)
            % asubs contains the subimages we will use
            FTs = avfft(app.Param.name,app.wins,app.Param,app.Posi);
            app.fftsub1 = reshape(FTs(1,:,:),app.Param.subwindow_size,app.Param.subwindow_size);
            app.fftsub2 = reshape(FTs(2,:,:),app.Param.subwindow_size,app.Param.subwindow_size);
            app.fftsub3 = reshape(FTs(3,:,:),app.Param.subwindow_size,app.Param.subwindow_size);
        end

        function gaussfilt(app)
                filter = gaussianFilter(ceil(2*app.Param.sigma),app.Param.sigma);
                app.spsub1 = filter2(filter,app.fftsub1);
                app.spsub2 = filter2(filter,app.fftsub2);
                app.spsub3 = filter2(filter,app.fftsub3);
        end

        function cutfun(app)
            app.fftsubcut1 = app.spsub1;
            app.fftsubcut2 = app.spsub2;
            app.fftsubcut3 = app.spsub3;
            ic = app.Param.subwindow_size*1/2+1;
            jc = app.Param.subwindow_size*1/2+1;
            [I,J] = ndgrid(1:app.Param.subwindow_size,1:app.Param.subwindow_size);
            M = double((I-ic).^2+(J-jc).^2<=(app.Param.cut)^2);
            app.fftsubcut1(M>0) = 0;
            app.fftsubcut2(M>0) = 0;
            app.fftsubcut3(M>0) = 0;
        end

        function affichcutfft(app)
            %display the cut and filtered fourier transform images
            imagesc(app.fftsubcut1,Parent=app.UIAxesIm1FT)
            axes(app.UIAxesIm1FT);
            zoom(app.Param.zoom)
            set(app.UIAxesIm1FT,Visible='off')

            imagesc(app.fftsubcut2,Parent=app.UIAxesIm2FT)
            axes(app.UIAxesIm2FT);
            zoom(app.Param.zoom)
            set(app.UIAxesIm2FT,Visible='off')

            imagesc(app.fftsubcut3,Parent=app.UIAxesIm3FT)
            axes(app.UIAxesIm3FT);
            zoom(app.Param.zoom)
            set(app.UIAxesIm3FT,Visible='off')
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %save initial settings as app properties 
            app.Param=varargin{1};

            app.Anamesi = cleanfolder([app.Param.name{1}]);

            %display 3 random tiles
            calculregion(app) %determines possible subimage locations
            affichim(app) %chooses subimages and displays

            %calculate FT 
            % blur and cut with initial values sigma, cut
            fftcalculus(app);
            gaussfilt(app);
            cutfun(app);

            % display initial FT plots - where to store these?  A property
            % of app, or an external object(?) previously in...
            affichcutfft(app);
            %display initial binarized plots
            handles = proporfft(handles);
            handles = affichproporfft(handles);

            %the orientation line and ellipse?
            handles = filter(handles);
            handles = trace_defo(handles);

            %not sure what is set to non-visible here
            set(get(handles.filt1,'children'),'visible','off')
            set(get(handles.filt2,'children'),'visible','off')
            set(get(handles.filt3,'children'),'visible','off')

            %??
            KB=handles.Param.subwindow_size^2*9.53674e-7*8;
            KBwhole  = KB*handles.regl;
            set(handles.sizesub,'String',sprintf(['One image: ' num2str(floor(KBwhole)) ' MB']));
            % Update handles structure
            guidata(hObject, handles);
            
            %and possible return something, if needed
            varargout{1} = handles;

        end

        % Callback function
        function ButtonPushed(app, event)
            array=randi(100, 12, 12);
            imshow(array, [], Parent=app.UIAxesIm1);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {590, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxesIm1 = uiaxes(app.LeftPanel);
            app.UIAxesIm1.XLimitMethod = 'tight';
            app.UIAxesIm1.YLimitMethod = 'tight';
            app.UIAxesIm1.ZLimitMethod = 'tight';
            app.UIAxesIm1.XTick = [];
            app.UIAxesIm1.YTick = [];
            app.UIAxesIm1.ZTick = [];
            app.UIAxesIm1FT = uiaxes(app.LeftPanel);
            app.UIAxesIm1FT.XLimitMethod = 'tight';
            app.UIAxesIm1FT.YLimitMethod = 'tight';
            app.UIAxesIm1FT.ZLimitMethod = 'tight';
            app.UIAxesIm1FT.XTick = [];
            app.UIAxesIm1FT.YTick = [];
            app.UIAxesIm1FT.ZTick = [];
            app.UIAxesIm2 = uiaxes(app.LeftPanel);
            app.UIAxesIm2.XLimitMethod = 'tight';
            app.UIAxesIm2.YLimitMethod = 'tight';
            app.UIAxesIm2.ZLimitMethod = 'tight';
            app.UIAxesIm2.XTick = [];
            app.UIAxesIm2.YTick = [];
            app.UIAxesIm2.ZTick = [];
            app.UIAxesIm3 = uiaxes(app.LeftPanel);
            app.UIAxesIm3.XLimitMethod = 'tight';
            app.UIAxesIm3.YLimitMethod = 'tight';
            app.UIAxesIm3.ZLimitMethod = 'tight';
            app.UIAxesIm3.XTick = [];
            app.UIAxesIm3.YTick = [];
            app.UIAxesIm3.ZTick = [];
            app.UIAxesIm2FT = uiaxes(app.LeftPanel);
            app.UIAxesIm2FT.XLimitMethod = 'tight';
            app.UIAxesIm2FT.YLimitMethod = 'tight';
            app.UIAxesIm2FT.ZLimitMethod = 'tight';
            app.UIAxesIm2FT.XTick = [];
            app.UIAxesIm2FT.YTick = [];
            app.UIAxesIm2FT.ZTick = [];
            app.UIAxesIm3FT = uiaxes(app.LeftPanel);
            app.UIAxesIm3FT.XLimitMethod = 'tight';
            app.UIAxesIm3FT.YLimitMethod = 'tight';
            app.UIAxesIm3FT.ZLimitMethod = 'tight';
            app.UIAxesIm3FT.XTick = [];
            app.UIAxesIm3FT.YTick = [];
            app.UIAxesIm3FT.ZTick = [];
            app.shuffleButton = uibutton(app.LeftPanel, 'push');
            app.shuffleButton.Text = 'shuffle';
            app.chooseButton = uibutton(app.LeftPanel, 'push');
            app.chooseButton.Text = 'choose';
            app.zoomEditFieldLabel = uilabel(app.LeftPanel);
            app.zoomEditFieldLabel.Text = 'zoom';
            app.zoomEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.sigmaEditFieldLabel = uilabel(app.RightPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.RightPanel, 'numeric');
            app.sigmaEditField.Limits = [0 1];
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.sigmaSlider = uislider(app.RightPanel);
            app.sigmaSlider.Limits = [0 1];
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.sigmaSlider_2 = uislider(app.RightPanel);
            app.sigmaSlider_2.Limits = [0 1];
            app.cutEditFieldLabel = uilabel(app.RightPanel);
            app.cutEditFieldLabel.Text = 'cut';
            app.cutEditField = uieditfield(app.RightPanel, 'numeric');
            app.cutEditField.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end